classdef Fdemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        FolderEditField       matlab.ui.control.EditField
        FolderEditFieldLabel  matlab.ui.control.Label
        ChooseFolderButton    matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        HighlightsTab         matlab.ui.container.Tab
        CreateImageButton     matlab.ui.control.Button
        HighlightSlider       matlab.ui.control.Slider
        HighlightSliderLabel  matlab.ui.control.Label
        Image2Slider          matlab.ui.control.Slider
        Image2SliderLabel     matlab.ui.control.Label
        Image1Slider          matlab.ui.control.Slider
        Image1SliderLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        ImageCurtainTab       matlab.ui.container.Tab
        CurtainEffectButton   matlab.ui.control.Button
        TimeLapseTab          matlab.ui.container.Tab
        PlayButton            matlab.ui.control.Button
        ImagesSlider          matlab.ui.control.Slider
        ImagesSliderLabel     matlab.ui.control.Label
        UIAxes1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        HTML_curtain
        CurtainFigure    % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            folder = uigetdir();
            if folder == 0
                error('你没有选择任何文件夹');
            end
            global zfolder            % 声明全局变量
            zfolder = folder;

            imageFiles = dir(fullfile(zfolder, '*.jpg'));
            % 按文件名自然排序（例如按年份顺序）
            [~, idx] = sort_nat({imageFiles.name});
            imageFiles = imageFiles(idx);
            
            function [sorted, idx] = sort_nat(c)
                % 自然排序字符串 cell 数组
                [~, idx] = sort_nat_internal(c);
                sorted = c(idx);
            end
            
            function [sorted, ndx, dbg] = sort_nat_internal(c,mode) 
                [sorted, ndx] = sort(c);
                dbg = {};
            end
            
            % 读取图像
            imgs = cell(1, numel(imageFiles));
            for i = 1:numel(imageFiles)
                imgs{i} = imread(fullfile(zfolder, imageFiles(i).name));
            end
            
            % 调用你的函数
            resultImgs = multiple_image_analysis(imgs);
            global rresultImgs
            rresultImgs = resultImgs;

            % 获取前两张图片
            imageFiles = dir(fullfile(folder, '*.jpg'));
            if numel(imageFiles) < 2
                error('该文件夹下至少需要 2 张 .jpg 图片');
            end
            app.FolderEditField.Value = folder;


        end

        % Button down function: ImageCurtainTab
        function ImageCurtainTabButtonDown(app, event)
            
        end

        % Button pushed function: CurtainEffectButton
        function CurtainEffectButtonPushed(app, event)
            global zfolder
            imageFiles = dir(fullfile(zfolder, '*.jpg'));
            if numel(imageFiles) < 2
                error('该文件夹下至少需要 2 张 .jpg 图片');
            end
             % 图片文件路径
            pic1_path = fullfile(zfolder, imageFiles(1).name); % 第一张图片
            pic2_path = fullfile(zfolder, imageFiles(2).name); % 第二张图片
            
            % 读取原始图像
            img1 = imread(pic1_path);
            img2 = imread(pic2_path);
            
            disp('原始图像读取成功!');
            disp(['图片1尺寸: ', num2str(size(img1))]);
            disp(['图片2尺寸: ', num2str(size(img2))]);
            
            %%
            % 图像配准处理
            disp('开始进行图像配准...');
            try
                % 调用你的函数进行图像配准
                [matched, imgs, highlights] = two_image_analysis({img1, img2});
                
                disp('图像配准完成!');
                disp(['配准结果: matched = ', num2str(matched)]);
                disp(['配准后图像1尺寸: ', num2str(size(imgs{1}))]);
                disp(['配准后图像2尺寸: ', num2str(size(imgs{2}))]);
                
                % 使用配准后的图像
                processed_img1 = imgs{1};
                processed_img2 = imgs{2};
                
            catch ME
                warning('图像配准失败，将使用原始图像: %s', ME.message);
                % 如果配准失败，使用原始图像
                processed_img1 = img1;
                processed_img2 = img2;
            end
            
            %%
            % 配置参数
            curtain_html_dir = 'curtain'; % HTML文件存放目录
            
            % 使用文件夹名称作为数据集名称,确保每个文件夹有独立的HTML目录
            [~, folder_name] = fileparts(zfolder);
            select_data_name = [folder_name, '_registration']; % 添加标识表示这是配准对比
            select_data_name = regexprep(select_data_name, '[^\w]', '_');
            
            % 设置路径
            dataset_html_dir = fullfile(curtain_html_dir, select_data_name);
            disp(['拷贝目标路径: ', dataset_html_dir]);
            
            % 检查并创建目录
            if exist(dataset_html_dir, 'dir') ~= 7
                % 从curtain目录复制文件
                if exist('curtain', 'dir') == 7
                    copyfile('curtain', dataset_html_dir);
                else
                    mkdir(dataset_html_dir);
                end
            end
            
            % 创建img文件夹
            img_folder = fullfile(dataset_html_dir, "img");
            if ~exist(img_folder, 'dir')
                mkdir(img_folder);
            end
            
            disp(['图片文件夹路径: ', img_folder]);
            
            %%
            % 保存配准后的两张图像用于窗帘效果展示
            try
                % 保存配准后的第一张图像作为pic_1.jpg（窗帘左侧）
                imwrite(processed_img1, fullfile(img_folder, "pic_1.jpg"));
                
                % 保存配准后的第二张图像作为pic_2.jpg（窗帘右侧）
                imwrite(processed_img2, fullfile(img_folder, "pic_2.jpg"));
                
                disp('配准后图像保存成功!');
                disp(['窗帘左侧 (pic_1.jpg): 配准后的图像1 (imgs{1})']);
                disp(['窗帘右侧 (pic_2.jpg): 配准后的图像2 (imgs{2})']);
                
            catch ME
                error('保存配准后图像失败: %s', ME.message);
            end


                % 如果HTML组件已存在，先删除
                if ~isempty(app.HTML_curtain) && isvalid(app.HTML_curtain)
                    delete(app.HTML_curtain);
                end
                
                % 在Time Lapse选项卡中创建HTML组件
                app.HTML_curtain = uihtml(app.ImageCurtainTab);
                
                % 设置HTML组件的位置 - 根据您的布局调整
                % 假设按钮在上方，留出空间给HTML组件
                app.HTML_curtain.Position = [80 80 680 380]; % [x, y, width, height]
                
                % 设置HTML源文件
                app.HTML_curtain.HTMLSource = fullfile(dataset_html_dir, 'sliding_curtain.html');
                
                disp('嵌入式窗帘效果创建完成！');


                %% 弹出新窗口的方法
                % % 直接在这里创建窗帘效果窗口，不调用其他方法
                % % 如果窗帘窗口已存在，先关闭
                % if ~isempty(app.CurtainFigure) && isvalid(app.CurtainFigure)
                %     close(app.CurtainFigure);
                % end
                % 
                % % 创建新的窗帘效果窗口
                % app.CurtainFigure = uifigure('Name', '窗帘效果', 'Position', [100 100 850 500]);
                % 
                % % 创建HTML组件
                % app.HTML_curtain = uihtml(app.CurtainFigure);
                % app.HTML_curtain.Position = [20 20 810 460];
                % app.HTML_curtain.HTMLSource = fullfile(dataset_html_dir, 'sliding_curtain.html');
                % 
                % disp('窗帘效果窗口创建完成！');
                
            % catch ME
            %     uialert(app.UIFigure, ['创建窗帘效果时出错: ' ME.message], '错误');
            % end

        end

        % Button pushed function: CreateImageButton
        function CreateImageButtonPushed(app, event)

            global zfolder
            imageFiles = dir(fullfile(zfolder, '*.jpg'));
            if numel(imageFiles) < 2
                error('该文件夹下至少需要 2 张 .jpg 图片');
            end

            % 图片文件路径
            pic1_path = fullfile(zfolder, imageFiles(1).name);
            pic2_path = fullfile(zfolder, imageFiles(2).name);
                

            % 读取两张图片
            img1 = imread(pic1_path);
            img2 = imread(pic2_path);
            
            [matched,imgs,highlights] = two_image_analysis({img1, img2});
            
            %%
            img1_double = im2double(imgs{1}); % 将 uint8 转换为 double，并自动归一化到 [0, 1]
            img2_double = im2double(imgs{2}); % 将 uint8 转换为 double，并自动归一化到 [0, 1]
            
            highlights_norm = highlights; % 假设 highlights 已经归一化到 [0, 1]
            
            %% 2. 设置透明度权重 (alpha values)
            % alpha1 = 0.4; % img1 的透明度权重
            % alpha2 = 0.3; % img2 的透明度权重
            % alpha_highlights = 0.5; % highlights 的透明度权重

            alpha1 = app.Image1Slider.Value;
            alpha2 = app.Image2Slider.Value;
            alpha_highlights = app.HighlightSlider.Value;
            
            base_blend = (alpha1 / (alpha1 + alpha2)) * img1_double + ...
                         (alpha2 / (alpha1 + alpha2)) * img2_double; % 归一化混合，确保总和为1
            
            combined_image = alpha_highlights * highlights_norm + (1 - alpha_highlights) * base_blend;


            imshow(combined_image, 'Parent', app.UIAxes); % 'Parent' specifies where to display
            title(app.UIAxes, sprintf('HlightImage (alpha1=%.2f, alpha2=%.2f, highlights=%.2f)', ...
                                       alpha1, alpha2, alpha_highlights));
            % Optional: Adjust axes limits if needed
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = 'none'; % Hide X axis
            app.UIAxes.YColor = 'none'; % Hide Y axis

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global zfolder
            imageFiles = dir(fullfile(zfolder, '*.jpg'));

            % % 按文件名自然排序（例如按年份顺序）
            % [~, idx] = sort_nat({imageFiles.name});
            % imageFiles = imageFiles(idx);
            % 
            % function [sorted, idx] = sort_nat(c)
            %     % 自然排序字符串 cell 数组
            %     [~, idx] = sort_nat_internal(c);
            %     sorted = c(idx);
            % end
            % 
            % function [sorted, ndx, dbg] = sort_nat_internal(c,mode) 
            %     [sorted, ndx] = sort(c);
            %     dbg = {};
            % end
            % 
            % % 读取图像
            % imgs = cell(1, numel(imageFiles));
            % for i = 1:numel(imageFiles)
            %     imgs{i} = imread(fullfile(zfolder, imageFiles(i).name));
            % end
            % 
            % % 调用你的函数
            % resultImgs = multiple_image_analysis(imgs);

            global rresultImgs
            i = floor(app.ImagesSlider.Value);

            if i > length(rresultImgs)
                msgbox(['The number of images in the folder is',num2str(length(rresultImgs)),' which is less than 10']);
            else
                imshow(rresultImgs{i}, 'Parent', app.UIAxes1); % 'Parent' specifies where to display
                app.UIAxes1.XTick = [];
                app.UIAxes1.YTick = [];
                app.UIAxes1.XColor = 'none'; % Hide X axis
                app.UIAxes1.YColor = 'none'; % Hide Y axis
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HighlightsTab = uitab(app.TabGroup);
            app.HighlightsTab.Title = 'Highlights';
            app.UIAxes = uiaxes(app.HighlightsTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Image1SliderLabel = uilabel(app.HighlightsTab);
            app.Image1SliderLabel.Text = 'Image1';
            app.Image1Slider = uislider(app.HighlightsTab);
            app.Image1Slider.Limits = [0 1];
            app.Image2SliderLabel = uilabel(app.HighlightsTab);
            app.Image2SliderLabel.Text = 'Image2';
            app.Image2Slider = uislider(app.HighlightsTab);
            app.Image2Slider.Limits = [0 1];
            app.HighlightSliderLabel = uilabel(app.HighlightsTab);
            app.HighlightSliderLabel.Text = 'Highlight';
            app.HighlightSlider = uislider(app.HighlightsTab);
            app.HighlightSlider.Limits = [0 1];
            app.CreateImageButton = uibutton(app.HighlightsTab, 'push');
            app.CreateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateImageButtonPushed, true);
            app.CreateImageButton.Text = 'Create Image';
            app.ImageCurtainTab = uitab(app.TabGroup);
            app.ImageCurtainTab.Title = 'Image Curtain';
            app.ImageCurtainTab.ButtonDownFcn = createCallbackFcn(app, @ImageCurtainTabButtonDown, true);
            app.CurtainEffectButton = uibutton(app.ImageCurtainTab, 'push');
            app.CurtainEffectButton.ButtonPushedFcn = createCallbackFcn(app, @CurtainEffectButtonPushed, true);
            app.CurtainEffectButton.Text = 'CurtainEffect';
            app.TimeLapseTab = uitab(app.TabGroup);
            app.TimeLapseTab.Title = 'Time Lapse';
            app.UIAxes1 = uiaxes(app.TimeLapseTab);
            app.UIAxes1.XTick = [];
            app.UIAxes1.XTickLabel = '';
            app.UIAxes1.YTick = [];
            app.ImagesSliderLabel = uilabel(app.TimeLapseTab);
            app.ImagesSliderLabel.Text = 'Images';
            app.ImagesSlider = uislider(app.TimeLapseTab);
            app.ImagesSlider.Limits = [1 10];
            app.ImagesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.ImagesSlider.Value = 1;
            app.PlayButton = uibutton(app.TimeLapseTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ChooseFolderButton = uibutton(app.Panel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FolderEditFieldLabel = uilabel(app.Panel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fdemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end